classdef MRXCATwERIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRXCATwithERICUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Simulation                      matlab.ui.container.Tab
        Acq_Recon                       matlab.ui.container.Panel
        SamplereconstructButton         matlab.ui.control.Button
        numberOfDCEframes               matlab.ui.control.Label
        accelerationFactor              matlab.ui.control.Label
        PlottrajectoryCheckBox          matlab.ui.control.CheckBox
        CalculatetrajectoryButton       matlab.ui.control.Button
        matrixSize                      matlab.ui.control.Label
        CompressedsensingreconCheckBox  matlab.ui.control.CheckBox
        DCE_frameLength                 matlab.ui.control.NumericEditField
        DCEtimeframelengthsLabel        matlab.ui.control.Label
        ReconstructionParametersLabel   matlab.ui.control.Label
        MotioncorrectionCheckBox        matlab.ui.control.CheckBox
        SamplingtrajectoryDropDown      matlab.ui.control.DropDown
        SamplingtrajectoryDropDownLabel  matlab.ui.control.Label
        TrajectoryPlot                  matlab.ui.control.UIAxes
        ViewGT                          matlab.ui.container.Panel
        ImageContrastSlider             matlab.ui.control.Slider
        ImageContrastLabel              matlab.ui.control.Label
        AnimationSpeedfpsSlider         matlab.ui.control.Slider
        AnimationSpeedfpsSliderLabel    matlab.ui.control.Label
        StartStopAnimationButton        matlab.ui.control.StateButton
        timeAxes                        matlab.ui.control.UIAxes
        Coronal                         matlab.ui.control.UIAxes
        Sagittal                        matlab.ui.control.UIAxes
        Axial                           matlab.ui.control.UIAxes
        Setup                           matlab.ui.container.Panel
        contrastSamplingTime            matlab.ui.control.NumericEditField
        contrastsamplingtimesLabel      matlab.ui.control.Label
        ContrastLabel                   matlab.ui.control.Label
        injectionTime                   matlab.ui.control.NumericEditField
        injectiontimesLabel             matlab.ui.control.Label
        relaxivity                      matlab.ui.control.NumericEditField
        relaxivitysmMLabel              matlab.ui.control.Label
        BTLabel                         matlab.ui.control.Label
        B0_sim_dropdown                 matlab.ui.control.DropDown
        CalculateNewGroundTruthButton   matlab.ui.control.Button
        IncluderespiratorymotionCheckBox  matlab.ui.control.CheckBox
        RespirationLabel                matlab.ui.control.Label
        RespPeriod                      matlab.ui.control.NumericEditField
        breathingperiodsLabel           matlab.ui.control.Label
        scanTime                        matlab.ui.control.NumericEditField
        scantimeminLabel                matlab.ui.control.Label
        alpha_sim                       matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        MRIAcquisitionLabel             matlab.ui.control.Label
        TE_sim                          matlab.ui.control.NumericEditField
        TEmsLabel                       matlab.ui.control.Label
        TR_sim                          matlab.ui.control.NumericEditField
        TRmsLabel                       matlab.ui.control.Label
        ReLoadDefaultParametersButton   matlab.ui.control.Button
        SpatialResolutionmmLabel        matlab.ui.control.Label
        footheadRes                     matlab.ui.control.NumericEditField
        footheadEditFieldLabel          matlab.ui.control.Label
        anteriorposteriorRes            matlab.ui.control.NumericEditField
        anteriorposteriorEditFieldLabel  matlab.ui.control.Label
        leftrightRes                    matlab.ui.control.NumericEditField
        leftrightEditFieldLabel         matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
        reset                           matlab.ui.control.Button
        RestartLabel                    matlab.ui.control.Label
        SaveResultsCallback             matlab.ui.control.Button
        SavingTitle                     matlab.ui.control.Label
        ViewResults                     matlab.ui.container.Panel
        ImageContrastSlider_results     matlab.ui.control.Slider
        ImageContrastSlider_2Label      matlab.ui.control.Label
        AnimationSpeedfpsSlider_results  matlab.ui.control.Slider
        AnimationSpeedfpsSlider_2Label  matlab.ui.control.Label
        StartStopAnimationButton_results  matlab.ui.control.StateButton
        Axial_GT                        matlab.ui.control.UIAxes
        Sagittal_GT                     matlab.ui.control.UIAxes
        Coronal_GT                      matlab.ui.control.UIAxes
        timeAxes_results                matlab.ui.control.UIAxes
        Coronal_results                 matlab.ui.control.UIAxes
        Sagittal_results                matlab.ui.control.UIAxes
        Axial_results                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        appPath;        % the path for the app
        IMG_CP;         % the ground-truth simulated contrast (with motion) over the prescribed FOV
        finalRecon;     % the recon after (optional) motion correction and sampling
        timing;         % the timing for each frame in IMG_CP
        timing_DCE;     % the timing for each frame in finalRecon

        isSimulationLoaded; % check if simulation is loaded
        isTrajCalculated;   % check if trajectory is calculated
        isCartesian;    % check sampling type

        % current values for the GT figure/plot updates
        currentSl_GT;
        currentFrame_GT;
        currentX_GT;
        currentY_GT;

        % current values for the R figure/plot updates
        currentSl_R;
        currentFrame_R;
        currentFrame_GT1;
        currentX_R;
        currentY_R;

        % animation
        animate;        % view the simulation

        matrixRL;       % RL matrix size
        matrixAP;       % AP matrix size
        matrixFH;       % FH matrix size

        % trajectory specifics
        kx_samples;     % kx samples
        ky_samples;     % ky samples
        kz_samples;     % kz samples
        
        % Resp
        sg_signal;      % the output self-gating signal for respiratory correction

        nt;             % number of reconstructed DCE frames
        nROperFrame;    % number of readouts per DCE frame

        SNR;            % the chosen SNR
        Resp;           % struct containing respiratory recon info
        RespGT_waveform;% ground-truth resp phases over the scan

    end

    methods (Access = private)

        function updateFigureAxes(app)

            imSize = size(app.IMG_CP);

            contrast = app.ImageContrastSlider.Value;

            % axial
            aa = squeeze(app.IMG_CP(:,:,app.currentSl_GT,app.currentFrame_GT));
            imshow(aa,[0 contrast],'Parent',app.Axial);
            images.roi.Crosshair('Parent',app.Axial,...
                'Position',[app.currentY_GT app.currentX_GT], ...
                'LineWidth', 1, 'Color', 'r');

            % sagittal
            aa = squeeze(app.IMG_CP(:,app.currentY_GT,:,app.currentFrame_GT));
            imshow(flipud(rot90(aa,-1)),[0 contrast],'Parent',app.Sagittal);
            images.roi.Crosshair('Parent',app.Sagittal,...
                'Position',[imSize(1)-app.currentX_GT imSize(3)-app.currentSl_GT], ...
                'LineWidth', 1, 'Color', 'r');
            daspect(app.Sagittal,[app.footheadRes.Value/app.anteriorposteriorRes.Value imSize(2)/imSize(1) 1]);

            % coronal
            aa = squeeze(app.IMG_CP(app.currentX_GT,:,:,app.currentFrame_GT));
            imshow(flipud(rot90(aa,-1)),[0 contrast],'Parent',app.Coronal);
            images.roi.Crosshair('Parent',app.Coronal,...
                'Position',[imSize(2)-app.currentY_GT imSize(3)-app.currentSl_GT], ...
                'LineWidth', 1, 'Color', 'r');
            daspect(app.Coronal,[app.footheadRes.Value/app.leftrightRes.Value 1 1]);
        end

        function updateTimeAxes(app)
            dat = squeeze(app.IMG_CP(app.currentX_GT,app.currentY_GT,...
                app.currentSl_GT,:));
            app.timeAxes.XLabel.String = 'time (s)';
            plotTimes = app.timing;

            plot(app.timeAxes,plotTimes,dat,'k','LineWidth',2);
            xlim(app.timeAxes, [0 max(plotTimes)]);
            hold(app.timeAxes,'on');
            xline(app.timeAxes,plotTimes(app.currentFrame_GT),'r');
            hold(app.timeAxes,'off');
        end

        function updateFigureAxes_results(app)

            imSize = size(app.finalRecon);

            contrast = app.ImageContrastSlider_results.Value;

            % axial
            aa = squeeze(app.finalRecon(:,:,app.currentSl_R,app.currentFrame_R));
            imshow(aa,[0 contrast],'Parent',app.Axial_results);
            images.roi.Crosshair('Parent',app.Axial_results,...
                'Position',[app.currentY_R app.currentX_R], ...
                'LineWidth', 1, 'Color', 'b');

            % sagittal
            aa = squeeze(app.finalRecon(:,app.currentY_R,:,app.currentFrame_R));
            imshow(flipud(rot90(aa,-1)),[0 contrast],'Parent',app.Sagittal_results);
            images.roi.Crosshair('Parent',app.Sagittal_results,...
                'Position',[imSize(1)-app.currentX_R imSize(3)-app.currentSl_R], ...
                'LineWidth', 1, 'Color', 'b');
            daspect(app.Sagittal_results,[app.footheadRes.Value/app.anteriorposteriorRes.Value imSize(2)/imSize(1) 1]);

            % coronal
            aa = squeeze(app.finalRecon(app.currentX_R,:,:,app.currentFrame_R));
            imshow(flipud(rot90(aa,-1)),[0 contrast],'Parent',app.Coronal_results);
            images.roi.Crosshair('Parent',app.Coronal_results,...
                'Position',[imSize(2)-app.currentY_R imSize(3)-app.currentSl_R], ...
                'LineWidth', 1, 'Color', 'b');
            daspect(app.Coronal_results,[app.footheadRes.Value/app.anteriorposteriorRes.Value 1 1]);

            %% and the ground truth images above the results
            % axial
            aa = squeeze(app.IMG_CP(:,:,app.currentSl_R,app.currentFrame_GT1));
            imshow(aa,[0 contrast],'Parent',app.Axial_GT);
            images.roi.Crosshair('Parent',app.Axial_GT,...
                'Position',[app.currentY_R app.currentX_R], ...
                'LineWidth', 1, 'Color', 'r');

            % sagittal
            aa = squeeze(app.IMG_CP(:,app.currentY_R,:,app.currentFrame_GT1));
            imshow(flipud(rot90(aa,-1)),[0 contrast],'Parent',app.Sagittal_GT);
            images.roi.Crosshair('Parent',app.Sagittal_GT,...
                'Position',[imSize(1)-app.currentX_R imSize(3)-app.currentSl_R], ...
                'LineWidth', 1, 'Color', 'r');
            daspect(app.Sagittal_GT,[app.footheadRes.Value/app.anteriorposteriorRes.Value imSize(2)/imSize(1) 1]);

            % coronal
            aa = squeeze(app.IMG_CP(app.currentX_R,:,:,app.currentFrame_GT1));
            imshow(flipud(rot90(aa,-1)),[0 contrast],'Parent',app.Coronal_GT);
            images.roi.Crosshair('Parent',app.Coronal_GT,...
                'Position',[imSize(2)-app.currentY_R imSize(3)-app.currentSl_R], ...
                'LineWidth', 1, 'Color', 'r');
            daspect(app.Coronal_GT,[app.footheadRes.Value/app.anteriorposteriorRes.Value 1 1]);
        end

        function updateTimeAxes_results(app)
            dat = squeeze(app.finalRecon(app.currentX_R,app.currentY_R,...
                app.currentSl_R,:));
            app.timeAxes.XLabel.String = 'time (s)';
            plot(app.timeAxes_results,app.timing_DCE,dat,'--k','LineWidth',2);

            hold(app.timeAxes_results,'on');
            dat = squeeze(app.IMG_CP(app.currentX_R,app.currentY_R,...
                app.currentSl_R,:));
            plot(app.timeAxes_results,app.timing,dat,'k','LineWidth',2);
            
            xlim(app.timeAxes_results, [0 max([app.timing_DCE(:); app.timing(:)])]);
            xline(app.timeAxes_results,app.timing(app.currentFrame_GT1),'r');
            legend(app.timeAxes_results,'simulation','ground truth')
            hold(app.timeAxes_results,'off');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.appPath = erase(mfilename('fullpath'),'MRXCATwERIC');
            addpath(genpath(app.appPath))
            %%%%%%%%
            % explicitly add bart path, note will change depending on user
            addpath(genpath('/opt/amc/bart-0.4.03/'))
            setenv('TOOLBOX_PATH','/opt/amc/bart-0.4.03/bin')
            %%%%%%%%

            app.MRXCATwithERICUIFigure.Icon = 'axial_contrast.png';
            app.ReLoadDefaultParametersButton.Icon = 'icons8-settings-64.png';
            app.CalculateNewGroundTruthButton.Icon = 'icons8-injection-66.png';
            app.CalculatetrajectoryButton.Icon = 'pseudo_spiral.png';
            app.StartStopAnimationButton.Icon = 'icons8-circled-play-80.png';
            app.StartStopAnimationButton_results.Icon = 'icons8-circled-play-80.png';
            app.SamplereconstructButton.Icon = 'mri.png';

            % initialize some variables
            app.matrixRL = round(374/app.leftrightRes.Value);
            app.matrixAP = round(258/app.anteriorposteriorRes.Value);
            app.matrixFH = round(105/app.footheadRes.Value);
            app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);
            app.isSimulationLoaded = 0;
            app.isTrajCalculated = 0;
            app.isCartesian = 1;
        end

        % Button pushed function: ReLoadDefaultParametersButton
        function ReLoadDefaultParametersButtonPushed(app, event)
            clc;
            fName = uigetfile("dataExamples/*mat", "Select file to load");
            load(fName);
            disp('Loading default data')
            
            % first make sure all parameters are set to their default
            app.leftrightRes.Value = acqRes(2);
            app.anteriorposteriorRes.Value = acqRes(1);
            app.footheadRes.Value = acqRes(3);
            app.TR_sim.Value = TR;
            app.B0_sim_dropdown.Value = b0;
            app.TE_sim.Value = TE;
            app.alpha_sim.Value = FA;
            app.RespPeriod.Value = RespPeriod;
            app.IncluderespiratorymotionCheckBox.Value = addResp;
            app.relaxivity.Value = relaxivity;
            app.injectionTime.Value = contrast_time;
            app.scanTime.Value = length_scan/60;
            app.contrastSamplingTime.Value = DCE_temp;

            if addResp
                app.RespirationLabel.Visible = 'on';
                app.RespPeriod.Visible = 'on';
                app.IncluderespiratorymotionCheckBox.Enable = 'on';
                app.IncluderespiratorymotionCheckBox.Value = 1;
            else
                app.RespirationLabel.Enable = 'off';
                app.RespPeriod.Enable = 'off';
                app.IncluderespiratorymotionCheckBox.Enable = 'on';
                app.IncluderespiratorymotionCheckBox.Value = 0;
                app.MotioncorrectionCheckBox.Value = 0;
                app.MotioncorrectionCheckBox.Enable = 'off';
            end

            app.timing = timing;
            app.IMG_CP = IMG_CP;

            % view
            [xx,yy,zz,~] = size(app.IMG_CP);
            app.currentX_GT = round(xx/2);
            app.currentY_GT = round(yy/2);
            app.currentSl_GT = round(zz/2);
            app.currentFrame_GT = round(length(app.timing)/2);
            updateFigureAxes(app);
            updateTimeAxes(app);

            % reset matrix size and base trajectory
            app.SamplingtrajectoryDropDown.Value = 'Pseudo-spiral';
            [app.matrixRL, app.matrixAP, app.matrixFH] = size(app.IMG_CP,1:3);
            app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);

            app.isSimulationLoaded = 1;
        end

        % Window button down function: MRXCATwithERICUIFigure
        function MRXCATwithERICUIFigureWindowButtonDown(app, event)

            changeFlag = 0;
            % depending on current tab the button down looks for different click locations
            cTab = app.TabGroup.SelectedTab.Title;
            if strcmp(cTab, 'Simulation')
                if ~isempty(app.IMG_CP)
                    imSize = size(app.IMG_CP);

                    % check if point in the axial axis
                    xx = round(app.Axial.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize(1) && y > 0 && y < imSize(2)
                        app.currentX_GT = x;
                        app.currentY_GT = y;
                        changeFlag = 1;
                    end

                    % check if point in the coronal axis
                    xx = round(app.Coronal.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize(3) && y > 0 && y < imSize(2)
                        app.currentY_GT = imSize(2)-y;
                        app.currentSl_GT = imSize(3)-x;
                        changeFlag = 1;
                    end

                    % check if point in the sagittal axis
                    xx = round(app.Sagittal.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize(3) && y > 0 && y < imSize(2)
                        app.currentX_GT = imSize(1)-y;
                        app.currentSl_GT = imSize(3)-x;
                        changeFlag = 1;
                    end

                    % check if point in the time axis
                    xx = round(app.timeAxes.CurrentPoint);
                    % check that point makes sense
                    x = xx(5); y = xx(1);
                    [ ~, y ] = min( abs( app.timing-y ) );
                    if ~isempty(y)
                        if x > -1.5 && x < 1.5 && y > 1 && y < imSize(4)   % we can perform this x check since data are normalized!
                            app.currentFrame_GT = y;
                            changeFlag = 1;
                        end
                    end

                    if changeFlag
                        % update axes
                        updateTimeAxes(app);
                        updateFigureAxes(app);
                    end
                end
            else % cTab='Results'
                if ~isempty(app.finalRecon)

                    % first check in app.finalRecon
                    imSize = size(app.finalRecon);

                    % check if point in the axial axis
                    xx = round(app.Axial_results.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize(1) && y > 0 && y < imSize(2)
                        app.currentX_R = x;
                        app.currentY_R = y;
                        changeFlag = 1;
                    end

                    % check if point in the coronal axis
                    xx = round(app.Coronal_results.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize(3) && y > 0 && y < imSize(2)
                        app.currentY_R = imSize(2)-y;
                        app.currentSl_R = imSize(3)-x;
                        changeFlag = 1;
                    end

                    % check if point in the sagittal axis
                    xx = round(app.Sagittal_results.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize(3) && y > 0 && y < imSize(2)
                        app.currentX_R = imSize(1)-y;
                        app.currentSl_R = imSize(3)-x;
                        changeFlag = 1;
                    end

                    % second check in app.IMG_CP
                    imSize = size(app.IMG_CP);

                    % check if point in the axial axis
                    xx = round(app.Axial_GT.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize(1) && y > 0 && y < imSize(2)
                        app.currentX_R = x;
                        app.currentY_R = y;
                        changeFlag = 1;
                    end

                    % check if point in the coronal axis
                    xx = round(app.Coronal_GT.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize(3) && y > 0 && y < imSize(2)
                        app.currentY_R = imSize(2)-y;
                        app.currentSl_R = imSize(3)-x;
                        changeFlag = 1;
                    end

                    % check if point in the sagittal axis
                    xx = round(app.Sagittal_GT.CurrentPoint);
                    % check that point makes sense
                    x = xx(3); y = xx(1);
                    if x > 0 && x < imSize(3) && y > 0 && y < imSize(2)
                        app.currentX_R = imSize(1)-y;
                        app.currentSl_R = imSize(3)-x;
                        changeFlag = 1;
                    end

                    % check if point in the time axis
                    xx = round(app.timeAxes_results.CurrentPoint);
                    % check that point makes sense
                    x = xx(5); y = xx(1);
                    [ ~, y1 ] = min( abs( app.timing_DCE-y ) );
                    [ ~, y2 ] = min( abs( app.timing-y ) );
                    if ~isempty(y1)
                        if x > -1.5 && x < 1.5 && y1 > 1 && y1 < imSize(4)   % we can perform this x check since data are normalized!
                            app.currentFrame_R = y1;
                            app.currentFrame_GT1 = y2;
                            changeFlag = 1;
                        end
                    end

                    if changeFlag
                        % update all axes
                        updateTimeAxes_results(app);
                        updateFigureAxes_results(app);
                    end
                end
            end
        end

        % Value changed function: TR_sim
        function TR_simValueChanged(app, event)
            value = app.TR_sim.Value;
            if app.TE_sim.Value >= value
                uialert(app.MRXCATwithERICUIFigure,'TE must be less than TR', 'TR/TE warning!');
            end
        end

        % Value changed function: TE_sim
        function TE_simValueChanged(app, event)
            value = app.TE_sim.Value;
            if app.TR_sim.Value <= value
                uialert(app.MRXCATwithERICUIFigure,'TE must be less than TR', 'TR/TE warning!');
            end
        end

        % Value changed function: StartStopAnimationButton
        function StartStopAnimationButtonValueChanged(app, event)
            app.animate = app.StartStopAnimationButton.Value;
            while (app.animate)
                app.AnimationSpeedfpsSlider.Enable = 'off';
                % first get current phase
                if app.currentFrame_GT == size(app.IMG_CP,4)
                    app.currentFrame_GT = 1;
                else
                    app.currentFrame_GT = app.currentFrame_GT+1;
                end
                updateFigureAxes(app);
                updateTimeAxes(app);
                pause(1/app.AnimationSpeedfpsSlider.Value)
            end
            app.AnimationSpeedfpsSlider.Enable = 'on';
        end

        % Value changed function: StartStopAnimationButton_results
        function StartStopAnimationButton_resultsValueChanged(app, event)
            while (app.StartStopAnimationButton_results.Value)
                app.AnimationSpeedfpsSlider_results.Enable = 'off';
                % first get current phase
                if app.currentFrame_GT1 == size(app.IMG_CP,4)
                    app.currentFrame_R = 1;
                    app.currentFrame_GT1 = 1;
                else
                    [ ~, app.currentFrame_R ] = min(abs(app.timing_DCE - app.timing(app.currentFrame_GT1)));
                    app.currentFrame_GT1 = app.currentFrame_GT1+1;
                end
                updateFigureAxes_results(app);
                updateTimeAxes_results(app);
                pause(1/app.AnimationSpeedfpsSlider_results.Value)
            end
            app.AnimationSpeedfpsSlider_results.Enable = 'on';
        end

        % Value changed function: IncluderespiratorymotionCheckBox
        function IncluderespiratorymotionCheckBoxValueChanged(app, event)
            value = app.IncluderespiratorymotionCheckBox.Value;
            if value
                app.RespirationLabel.Enable = 'on';
                app.RespPeriod.Enable = 'on';
                app.breathingperiodsLabel.Enable = 'on';
                app.MotioncorrectionCheckBox.Enable = 'on';
            else
                app.RespirationLabel.Enable = 'off';
                app.RespPeriod.Enable = 'off';
                app.breathingperiodsLabel.Enable = 'off';
                app.MotioncorrectionCheckBox.Enable = 'off';
                app.MotioncorrectionCheckBox.Value = 0;
            end

        end

        % Button pushed function: CalculateNewGroundTruthButton
        function CalculateNewGroundTruthButtonPushed(app, event)
            clc;

            % grab parameters
            FA = app.alpha_sim.Value;
            TR = app.TR_sim.Value;
            TE = app.TE_sim.Value;
            b0 = app.B0_sim_dropdown.Value;
            rel = app.relaxivity.Value;
            Contrast = 'GRE';       % options - 'bSSFP','GRE'
            contrast_time = app.injectionTime.Value;
            length_scan = app.scanTime.Value*60;
            acqRes = [app.anteriorposteriorRes.Value, ...
                app.leftrightRes.Value, app.footheadRes.Value];

            % briefly load expiration IMG, let user decide start and stop
            % axial locations
            volName = [app.appPath 'utilities/XCAT_MAT_RESP/XCAT5D_RP_1_CP_1.mat'];
            load(volName,'IMG')
            IMG = squeeze(IMG(:,75:20:155,1:end-175));
            IMG = IMG/max(IMG(:));
            for i = 1:5
                tmpImg(:,:,i) = rot90(squeeze(IMG(:,i,:)),1);
            end
            cropFig=figure(4); montage(tmpImg,'size',[1 5]);
            set(cropFig,'Name','Draw foot-head coverage to simulate')
            set(cropFig,'Units','Normalized')
            set(cropFig,'Position',[0.0016 0.0481 0.95 0.8454])
            h = drawline(gca,'DrawingArea',[1 1 5*size(tmpImg,2)-1 size(tmpImg,1)-1]);
            maskSz = h.Position;
            if maskSz(3)>maskSz(4)
                FHind = ceil(maskSz(4)):floor(maskSz(3));
            else
                FHind = ceil(maskSz(3)):floor(maskSz(4));
                if isempty(FHind)
                    FHind = ceil(maskSz(3));
                end
            end
            tmpInd = flip(1:size(tmpImg,1));
            FHind = tmpInd(FHind);
            % update FH matrix size
            app.matrixFH = floor(length(FHind)/acqRes(3));
            if app.matrixFH == 0
                app.matrixFH = 1;
            end
            app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);
            msgbox(sprintf('New number of slices =  %i',app.matrixFH))
            close(cropFig);

            % Array with time points of each DCE frame, in seconds
            app.timing = (0:app.contrastSamplingTime.Value:length_scan);

            % Calculation of contrast curve based on DCE parameter values
            [enhanced_tissue,C,~] = contrast_curve_calc(contrast_time,app.timing);

            if app.IncluderespiratorymotionCheckBox.Value
                RespIntervals = round(generate_RR(100,0.28,length_scan*1000,1000*app.RespPeriod.Value));
                RespWaveTimes=cumsum([0,RespIntervals]);

                % generate sample indices for each unique motion state, 20
                % respiratory phases
                spokePhases = RespRadKspaceSort(length(app.timing),app.timing*1000, RespWaveTimes,20);
                app.RespGT_waveform = reorder_spokePhases(spokePhases);
                respFrames = 9;
            else    % no respiratory motion in simulation
                spokePhases = ones(length(app.timing),1);
                respFrames = 1;
            end
            %% Load in all resp frames, then downsample
            t_z = [56, 55, 53, 51, 47, 44, 41, 39, 38];
            t_x = [123, 122, 121, 119, 117, 115, 114, 113, 112];

            for rp = 1:respFrames

                volName = [app.appPath 'utilities/XCAT_MAT_RESP/XCAT5D_RP_' sprintf('%i',rp) '_CP_' sprintf('%i',1) '.mat'];
                disp(volName)
                load(volName,'IMG')

                %     which has an FOV:
                %     220 RL at 1.7 mm = 374; 152 AP at 1.7 mm = 258
                IMG = padarray(IMG,[0 11 0]); % padarray AP
                IMG = IMG(37:410,:,:);   % assume our x oversampling cuts out arms
                IMG = IMG(:,:,1:end-175);   % crop FH as in above
                IMG = IMG(:,:,FHind);

                %                 %     Create spherical tumor region in IMG
                %                 [px,py,pz] = meshgrid(1:size(IMG,2),1:size(IMG,1),1:size(IMG,3));
                %                 radius = 8;
                %
                %                 xc = t_x(rp); zc = t_z(rp); yc = 212;
                %                 logicalSphere = (px-xc).^2 + (py-yc).^2 +(pz-zc).^2 <= radius*radius;
                %                 IMG(logicalSphere) = 73;

                IMG_RP(:,:,:,rp) = IMG;
            end
            clear IMG

            if app.IncluderespiratorymotionCheckBox.Value
                % fill in other resp phases
                IMG_RP(:,:,:,10:17) = IMG_RP(:,:,:,8:-1:1);
                IMG_RP = cat(4,IMG_RP(:,:,:,1),IMG_RP(:,:,:,1),IMG_RP,IMG_RP(:,:,:,1));
                IMG_RP(:,:,:,11:20) = IMG_RP(:,:,:,10:-1:1);
            end

            % set matrix size
            nFrames = length(app.timing);
            RL = round(374/app.leftrightRes.Value);
            if mod(RL,2)==1
                RL = RL-1;
            end
            AP = round(258/app.anteriorposteriorRes.Value);
            if mod(AP,2)==1
                AP = AP-1;
            end
            FH = app.matrixFH;
            outputSize = [RL,AP,FH];
            IMG_CPtmp = zeros([outputSize, nFrames],'single');
            tmpTiming = app.timing;

            %% Convert to MRI contrast using a parfor loop to speed it up
            delete(gcp('nocreate'));
            parpool();

            % make a waitbar for the parfor loop
            WaitMessage = parfor_wait(nFrames, 'Waitbar', true);
            parfor t_idx = 1 : nFrames
                name = ['Generating contrast phase ' sprintf('%i',t_idx) ' of ' sprintf('%i',nFrames)];
                disp(name)
                WaitMessage.Send;

                time = tmpTiming(t_idx);
                if time >= contrast_time
                    dyn = true;  % If contrast is injected, dyn should be equal to true
                else
                    dyn = false;
                end

                % grab current respiratory state, need to average if multiple states
                % exist as a result of DCE sampling time
                tmpIMG = IMG_RP(:,:,:,spokePhases(t_idx));

                % convert to MR+DCE contrast
                tmp = single(XCAT_to_MR_DCE(tmpIMG,FA,TR,TE,Contrast,b0,1,dyn,enhanced_tissue,C(:,t_idx),rel));

                % downsample IMG to our resolution
                if outputSize(3) == 1
                    tmp_ds = imresize(tmp,outputSize(1:2));
                else
                    tmp_ds = imresize3(tmp,outputSize);
                end
                IMG_CPtmp(:,:,:,t_idx) = tmp_ds;
            end
            clear IMG_RP
            app.IMG_CP = IMG_CPtmp;
            delete(gcp('nocreate'));
            % Destroy the WaitMessage
            WaitMessage.Destroy;

            % correct orientation
            app.IMG_CP = fliplr(rot90(app.IMG_CP,-1))/max(app.IMG_CP(:));
            app.IMG_CP = flip(app.IMG_CP,3);

            % view
            [xx,yy,zz,nFrames] = size(app.IMG_CP);
            app.currentX_GT = round(xx/2);
            app.currentY_GT = round(yy/2);
            app.currentSl_GT = round(zz/2);
            app.currentFrame_GT = 1;
            updateFigureAxes(app);
            updateTimeAxes(app);

            app.isSimulationLoaded = 1;
            close(figure(4));
        end

        % Value changed function: ImageContrastSlider
        function ImageContrastSliderValueChanged(app, event)
            updateFigureAxes(app);
            updateTimeAxes(app);
        end

        % Value changed function: ImageContrastSlider_results
        function ImageContrastSlider_resultsValueChanged(app, event)
            updateFigureAxes_results(app);
            updateTimeAxes_results(app);
        end

        % Value changed function: leftrightRes
        function leftrightResValueChanged(app, event)
            value = app.leftrightRes.Value;
            if app.isCartesian
                % left right field of view is assumed to be 374 mm
                app.matrixRL = round(374/value);
                if mod(app.matrixRL,2)==1
                    app.matrixRL = app.matrixRL-1;
                end
            else    % make sure AP res changes with it
                app.anteriorposteriorRes.Value = value;
                app.matrixRL = ceil(374/value);
                if mod(app.matrixRL,2)==1
                    app.matrixRL = app.matrixRL-1;
                end
                app.matrixAP = app.matrixRL;    % square matrix
            end
            app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);

        end

        % Value changed function: anteriorposteriorRes
        function anteriorposteriorResValueChanged(app, event)
            value = app.anteriorposteriorRes.Value;
            if app.isCartesian
                % anterior posterior field of view is assumed to be 258 mm
                app.matrixAP = round(258/value);
                if mod(app.matrixAP,2)==1
                    app.matrixAP = app.matrixAP-1;
                end
            else % make sure RL res changes with it
                app.leftrightRes.Value = value;
                app.matrixRL = ceil(374/value);
                if mod(app.matrixRL,2)==1
                    app.matrixRL = app.matrixRL-1;
                end
                app.matrixAP = app.matrixRL;    % square matrix
            end
            app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);
        end

        % Value changed function: footheadRes
        function footheadResValueChanged(app, event)
            value = app.footheadRes.Value;
            % foot head field of view is assumed to be 105 mm
            app.matrixFH = round(105/value);
            if ~app.isCartesian
                % even number of slices for nufft
                if mod(app.matrixFH,2)==1
                    app.matrixFH = app.matrixFH-1;
                end
            end
            app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);
        end

        % Value changed function: SamplingtrajectoryDropDown
        function SamplingtrajectoryDropDownValueChanged(app, event)
            value = app.SamplingtrajectoryDropDown.Value;

            close(figure(200));   % close open selection window

            app.isTrajCalculated = 0;   % reset
            switch value
                case 'Cartesian'
                    % turn off motion correction
                    app.MotioncorrectionCheckBox.Value = 0;
                    app.MotioncorrectionCheckBox.Enable = 'off';
                    app.CalculatetrajectoryButton.Icon = 'cartesian.png';
                    app.isCartesian = 1;

                    app.matrixRL = round(374/app.leftrightRes.Value);
                    if mod(app.matrixRL,2)==1
                        app.matrixRL = app.matrixRL-1;
                    end
                    app.matrixAP = round(258/app.anteriorposteriorRes.Value);
                    if mod(app.matrixAP,2)==1
                        app.matrixAP = app.matrixAP-1;
                    end
%                     app.matrixFH = round(105/app.footheadRes.Value);
                    app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);


                case 'Pseudo-spiral'
                    % turn on compressed sensing
                    app.CompressedsensingreconCheckBox.Value = 1;
                    app.CompressedsensingreconCheckBox.Enable = 'on';
                    app.CalculatetrajectoryButton.Icon = 'pseudo_spiral.png';
                    app.isCartesian = 1;
                    app.MotioncorrectionCheckBox.Enable = 'on';
                    app.MotioncorrectionCheckBox.Text = 'Motion correction - auto-focus';

                    app.matrixRL = round(374/app.leftrightRes.Value);
                    if mod(app.matrixRL,2)==1
                        app.matrixRL = app.matrixRL-1;
                    end
                    app.matrixAP = round(258/app.anteriorposteriorRes.Value);
                    if mod(app.matrixAP,2)==1
                        app.matrixAP = app.matrixAP-1;
                    end
%                     app.matrixFH = round(105/app.footheadRes.Value);
                    app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);

                case 'Stack-of-stars'
                    % turn on compressed sensing
                    app.CompressedsensingreconCheckBox.Value = 1;
                    app.CompressedsensingreconCheckBox.Enable = 'on';
                    app.CalculatetrajectoryButton.Icon = 'radial.png';
                    app.isCartesian = 0;
                    app.MotioncorrectionCheckBox.Enable = 'on';
                    app.MotioncorrectionCheckBox.Text = 'Motion correction - soft-gating';

                    % set resolution equal to smaller of RL/AP
                    minRes = min([app.leftrightRes.Value app.anteriorposteriorRes.Value]);
                    app.leftrightRes.Value  = minRes;
                    app.anteriorposteriorRes.Value  = minRes;
                    % adjust matrix accordingly, zero-padding during
                    % sampling
                    app.matrixRL = round(374/minRes);
                    if mod(app.matrixRL,2)==1
                        app.matrixRL = app.matrixRL-1;
                    end
                    app.matrixAP = app.matrixRL;    % square matrix
%                     % even number of slices for nufft
%                     if mod(app.matrixFH,2)==1
%                         app.matrixFH = app.matrixFH-1;
%                         % also make sure IMG_CP slices are even
%                         if ~isempty(app.IMG_CP) && mod(size(app.IMG_CP,3),2)==1
%                             app.IMG_CP(:,:,end,:) = [];
%                         end
%                     end
                    app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);

                case 'Stack-of-spirals'
                    % turn on compressed sensing
                    app.CompressedsensingreconCheckBox.Value = 1;
                    app.CompressedsensingreconCheckBox.Enable = 'on';
                    app.CalculatetrajectoryButton.Icon = 'spiral.png';
                    app.isCartesian = 0;app.MotioncorrectionCheckBox.Enable = 'on';
                    app.MotioncorrectionCheckBox.Text = 'Motion correction - soft-gating';

                    % set resolution equal to smaller of RL/AP
                    minRes = min([app.leftrightRes.Value app.anteriorposteriorRes.Value]);
                    app.leftrightRes.Value  = minRes;
                    app.anteriorposteriorRes.Value  = minRes;
                    % adjust matrix accordingly, zero-padding during
                    % sampling
                    app.matrixRL = round(374/minRes);
                    if mod(app.matrixRL,2)==1
                        app.matrixRL = app.matrixRL-1;
                    end
                    app.matrixAP = app.matrixRL;    % square matrix
%                     % even number of slices for nufft
%                     if mod(app.matrixFH,2)==1
%                         app.matrixFH = app.matrixFH-1;
%                         % also make sure IMG_CP slices are even
%                         if ~isempty(app.IMG_CP) && mod(size(app.IMG_CP,3),2)==1
%                             app.IMG_CP(:,:,end,:) = [];
%                         end
%                     end
                    app.matrixSize.Text = sprintf('matrix size: RL=%i, AP=%i, FH=%i',app.matrixRL,app.matrixAP,app.matrixFH);
            end
        end

        % Button pushed function: CalculatetrajectoryButton
        function CalculatetrajectoryButtonPushed(app, event)
            clc;
            % grab relevant parameters
            DCETimeResolution = app.DCE_frameLength.Value;      % in seconds
            TR = app.TR_sim.Value;                              % in ms
            TE = app.TE_sim.Value;                              % in ms
            length_scan = app.scanTime.Value*60;                % in seconds

            switch app.SamplingtrajectoryDropDown.Value
                case 'Cartesian'
                    matrix = [app.matrixAP, app.matrixFH];
                    [ky, kz, DCETimeResolution] = calculateCartesian(matrix, length_scan, TR, DCETimeResolution);

                    % calculate and report undersampling per frame
                    app.nROperFrame = floor(DCETimeResolution*1000/TR);
                    app.nt = floor(length(ky)/app.nROperFrame);
                    app.numberOfDCEframes.Text = sprintf('number of frames=%i',app.nt);
                    app.numberOfDCEframes.Visible = 'on';

                    mask = zeros(matrix);
                    ind = sub2ind(matrix,ky(1:app.nROperFrame),kz(1:app.nROperFrame));
                    mask(ind) = 1;
                    mask = repmat(permute(mask,[3 1 2]),[app.matrixRL,1]);
                    R = numel(mask(:))/sum(mask(:)) /4*pi ;
                    app.accelerationFactor.Text = sprintf('R per frame=%0.1f',round(R,1));
                    app.accelerationFactor.Visible = 'on';

                    % update DCETimeResolution
                    app.DCE_frameLength.Value = DCETimeResolution;

                    if app.PlottrajectoryCheckBox.Value
                        cla(app.TrajectoryPlot, 'reset');
                        hold(app.TrajectoryPlot,'on');
                        ylabel(app.TrajectoryPlot,'k_y');
                        xlabel(app.TrajectoryPlot,'k_z');
                        axis(app.TrajectoryPlot,'square')
                        ylim(app.TrajectoryPlot,[-1 matrix(1)+1])
                        xlim(app.TrajectoryPlot,[-1 matrix(2)+1])
                        scatter(app.TrajectoryPlot,kz(1:app.nROperFrame),ky(1:app.nROperFrame), 'b.');
                        legend(app.TrajectoryPlot,'readout')
                    end

                    % store the kx,ky,kz coordinates
                    app.kx_samples = repmat((1:app.matrixRL)', [1 length(ky)]);
                    app.ky_samples = repmat(ky', [app.matrixRL 1]);
                    app.kz_samples = repmat(kz', [app.matrixRL 1]);

                case 'Pseudo-spiral'
                    matrix = [app.matrixAP, app.matrixFH];
                    [ky, kz, Sl] = calculatePseudoSpiral(matrix, length_scan, TR, DCETimeResolution);

                    % calculate and report undersampling per frame
                    app.nROperFrame = floor(DCETimeResolution*1000/TR);
                    app.nt = floor(length(ky)/app.nROperFrame);
                    app.numberOfDCEframes.Text = sprintf('number of frames=%i',app.nt);
                    app.numberOfDCEframes.Visible = 'on';

                    mask = zeros(matrix);
                    ind = sub2ind(matrix,ky(1:app.nROperFrame),kz(1:app.nROperFrame));
                    mask(ind) = 1;
                    mask = repmat(permute(mask,[3 1 2]),[app.matrixRL,1]);
                    R = numel(mask(:))/sum(mask(:)) /4*pi ;
                    app.accelerationFactor.Text = sprintf('R per frame=%0.1f',round(R,1));
                    app.accelerationFactor.Visible = 'on';

                    % specifics for plotting
                    k = cat(2,ky,kz);

                    if app.PlottrajectoryCheckBox.Value
                        viewTrajPseudoSpiral(k, Sl, matrix, app);
                    end

                    % store the kx,ky,kz coordinates
                    app.kx_samples = repmat((1:app.matrixRL)', [1 length(ky)]);
                    app.ky_samples = repmat(ky', [app.matrixRL 1]);
                    app.kz_samples = repmat(kz', [app.matrixRL 1]);

                case 'Stack-of-stars'
                    kx = linspace(-app.matrixRL/2,app.matrixRL/2,app.matrixRL)/app.matrixRL;
                    % one arm takes TR
                    nArms = ceil(length_scan/(TR/1000));
                    [kx, ky, kz, nArms, nSlcs] = calculateRSOSTraj(kx, nArms, app.matrixFH);

                    % update scan time
                    app.scanTime.Value = (nArms*TR/1000)/60;

                    % calculate and report undersampling per frame
                    timePerStack = nSlcs * (TR/1000);    % in seconds
                    nStacksperFrame = floor(DCETimeResolution/timePerStack);
                    app.nROperFrame = nStacksperFrame*nSlcs;
                    app.nt = floor(app.scanTime.Value*60/(timePerStack*nStacksperFrame));
                    app.numberOfDCEframes.Text = sprintf('number of frames=%i',app.nt);
                    app.numberOfDCEframes.Visible = 'on';
                    % update DCETimeResolution
                    app.DCE_frameLength.Value = timePerStack*nStacksperFrame;

                    % approximate R calculation, fully sampled radial
                    % is pi/2*Nphase(Cartesian), note this assumes
                    % each radial projection is unique per frame (should
                    % be with golden angle ordering!)
                    NyquistSampling = pi/2*app.matrixRL;
                    R = NyquistSampling/nStacksperFrame;
                    app.accelerationFactor.Text = sprintf('R per frame=%0.1f',round(R,1));
                    app.accelerationFactor.Visible = 'on';

                    if app.PlottrajectoryCheckBox.Value
                        viewTrajRSOS(cat(3,kx,ky,kz), nSlcs, nStacksperFrame, app);
                    end

                    % store the kx,ky,kz coordinates
                    app.kx_samples = kx;
                    app.ky_samples = ky;
                    app.kz_samples = kz;

                case 'Stack-of-spirals'

                    % one arm takes TR
                    nArms = ceil(length_scan/(TR/1000));
                    [kx, ky, kz, Ninterleaves, nArms, nSlcs] = ...
                        calculateSpiralTraj(app, nArms, app.matrixFH);

                    % update scan time
                    app.scanTime.Value = (nArms*TR/1000)/60;

                    % calculate and report undersampling per frame
                    timePerStack = nSlcs * (TR/1000);    % in seconds
                    nStacksperFrame = floor(DCETimeResolution/timePerStack);
                    app.nROperFrame = nStacksperFrame*nSlcs;
                    app.nt = floor(app.scanTime.Value*60/(timePerStack*nStacksperFrame));
                    app.numberOfDCEframes.Text = sprintf('number of frames=%i',app.nt);
                    app.numberOfDCEframes.Visible = 'on';
                    % update DCETimeResolution
                    app.DCE_frameLength.Value = timePerStack*nStacksperFrame;

                    % approximate R calculation, fully sampled frames with
                    % spirals should have Ninterleaves as calculated in
                    % calculateSpiralTraj
                    NyquistSampling = Ninterleaves;
                    R = NyquistSampling/nStacksperFrame;
                    app.accelerationFactor.Text = sprintf('R per frame=%0.1f',round(R,1));
                    app.accelerationFactor.Visible = 'on';

                    if app.PlottrajectoryCheckBox.Value
                        viewSpiralTraj(cat(3,kx,ky,kz), nSlcs, nStacksperFrame, app);
                    end

                    % store the kx,ky,kz coordinates
                    app.kx_samples = kx;
                    app.ky_samples = ky;
                    app.kz_samples = kz;

            end

            app.isTrajCalculated = 1;
        end

        % Button pushed function: SamplereconstructButton
        function SamplereconstructButtonPushed(app, event)
            clc;
            if app.isSimulationLoaded && app.isTrajCalculated

                % generate pop-up for additional options
                prompt = {'simulated SNR (1:100)'};
                definput = {'100'};
                % remove auto-focus option for nonCartesian
%                 if app.MotioncorrectionCheckBox.Value && ~app.isCartesian
%                     prompt = cat(1,prompt,{'motion correction: auto-focus (0) or soft-gating (1)'});
%                     definput = cat(1,definput,{'0'});
%                 end

                % determine how to get 'respiratory' signal for motion
                % correction
                if app.MotioncorrectionCheckBox.Value
                    prompt = cat(1,prompt,{'respiratory signal: use self-gating (0) or ground-truth (1)'});
                    definput = cat(1,definput,{'0'});
                end
                dlgtitle = 'Sampling and Recon options';
                dims = [1 50];
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.SNR = str2double(answer{1});

                app.Resp = []; % initialize Resp struct for saving
                if app.MotioncorrectionCheckBox.Value && ~app.isCartesian
                    isSoftGating = 1;%str2double(answer{2});    % remove auto-focus option for nonCartesian
                else
                    isSoftGating = 0;
                end

                [kspace] = sampleKSpace(app, app.isCartesian, app.SNR);

                % now we start the reconstruction
                if app.MotioncorrectionCheckBox.Value
                    % if motion correction and auto-focus enabled, there are 4
                    % steps to perform:
                    % 1. extract respiratory signal or use ground-truth
                    % 2. perform respiratory resolved (CS) recon
                    % 3. image-space registration to extract translations
                    % 4. apply translations original k-space (as phase
                    % shifts)

                    % step 1: get respiratory signal
                    useGT_resp = str2double(answer{2});
                    if useGT_resp % make our own GT respiratory signal
                        tmp = interp1(1:length(app.RespGT_waveform),app.RespGT_waveform,linspace(1,length(app.RespGT_waveform),size(kspace,2)));
                        % smooth over 1 second window
                        nSmooth = round(1/(app.TR_sim.Value/1000));
                        tmp = smooth(tmp,nSmooth);
                        app.sg_signal = tmp/max(tmp(:));
                        clear tmp;
                    else
                        % extract self gating signal
                        disp('Extracting self-gating signal...')
                        app.sg_signal = extractSG(app, kspace, app.isCartesian, strmatch(app.SamplingtrajectoryDropDown.Value, 'Stack-of-stars'));
                    end
                    % loop over all readouts and sort k-space, average when location is not empty
                    % the number of reconstructed respiratory phases
                    if isSoftGating
                        nRespPhases = 4;
                    else
                        nRespPhases = min([6,round(app.nt/2)]);
                    end
                    app.Resp.nRespPhases = nRespPhases;

                    % this vector sorts into resp bins
                    RESPPhases = RespSort(app.sg_signal,nRespPhases);

                    % if soft-gating, skip resp-resolved recon and
                    % registration
                    if ~isSoftGating
                        app.Resp.type = 'auto-focus';

                        [kspaceRespSorted, trajSorted] = sortRespKspace(kspace,RESPPhases,app,app.isCartesian);

                        % step 2: compressed sensing 4D respiratory resolved
                        % recon. if Cartesian, use bart, otherwise use nufft_3d
                        % and conjugant gradient
                        disp('Performing respiratory-resolved reconstruction...')
%                         if app.isCartesian
                            outputImg = doBART(kspaceRespSorted);
%                         else
%                             outputImg = nonCartesian_CS(app, kspace, trajSorted, kspaceRespSorted, 0.01, 0);    % no soft-gating
%                         end

                        app.Resp.RespOutputImg = outputImg;

                        % step 3: 3D rigid image registration to end-expiration
                        % extracting translations over user-defined volume
                        % (usually pancreas)
                        disp('Respiratory image registration...')
                        [transforms tra_table] = respRegister(outputImg, app);
                        app.Resp.Transforms = tra_table;

                        % step 4: apply translations back to original k-space
                        kspace_corrected = applyPhaseShifts(kspace, RESPPhases, transforms, app);

                        kspace = kspace_corrected;
                    else
                        app.Resp.Type = 'soft-gating';
                    end
                end

                % sort into DCE frames
                [kspaceDCESorted, trajDCESorted] = sortDCEKspace(kspace, app, app.isCartesian);

                % perform final recon
                if app.isCartesian
                    outputImg = doBART(kspaceDCESorted);
                    app.finalRecon = fliplr(rot90(outputImg,-1))/max(outputImg(:));
                else
                    app.finalRecon  = nonCartesian_CS(app, kspace, trajDCESorted, kspaceDCESorted, 0.02, isSoftGating);

                    % remove padding to equal the size of IMG_CP, RL is always bigger than AP
                    if size(app.finalRecon,1) > size(app.IMG_CP,1)
                        toRemove = (size(app.finalRecon,1)-size(app.IMG_CP,1))/2;
                        app.finalRecon([1:toRemove,end-toRemove+1:end],:,:,:) = [];
                    end
                end

                % once the recon is complete, open up results tab
                app.TabGroup.SelectedTab = app.ResultsTab;
                [xx,yy,zz,~] = size(app.finalRecon);
                app.currentX_R = round(xx/2);
                app.currentY_R = round(yy/2);
                app.currentSl_R = round(zz/2);
                app.currentFrame_R = 1;
                app.currentFrame_GT1 = 1;
                % make DCE timing to compare with GT timing (app.timing)
                app.timing_DCE = app.DCE_frameLength.Value/2:app.DCE_frameLength.Value:(app.nt)*app.DCE_frameLength.Value;
                updateFigureAxes_results(app);
                updateTimeAxes_results(app);

            else
                uialert(app.MRXCATwithERICUIFigure,'Simulation and trajectory need to be defined!','Cannot start sampling')
            end
        end

        % Button pushed function: SaveResultsCallback
        function SaveResultsCallbackButtonPushed(app, event)
            % grab specifics of simulation

            savePrefix = ['simulation_results_' datestr(now,30)] ;
            saveFolder = fullfile(app.appPath, 'results');
            if ~exist(saveFolder,'dir')
                mkdir(saveFolder);
            end
            saveName =  fullfile(saveFolder, savePrefix);

            % save the simulation information in a results.sim struct
            results = [];
            results.sim.B0          = app.B0_sim_dropdown.Value;
            results.sim.TR          = app.TR_sim.Value;
            results.sim.TE          = app.TE_sim.Value;
            results.sim.alpha       = app.alpha_sim.Value;
            results.sim.scanTime    = app.scanTime.Value;

            results.sim.matrixRL    = app.matrixRL;
            results.sim.matrixAP    = app.matrixAP;
            results.sim.matrixFH    = app.matrixFH;
            results.sim.resRL       = app.leftrightRes.Value;
            results.sim.resAP       = app.anteriorposteriorRes.Value;
            results.sim.resFH       = app.footheadRes.Value;

            results.sim.relaxivity  = app.relaxivity.Value;
            results.sim.injectionTime   = app.injectionTime.Value;
            results.sim.contrastSamplingTime = app.contrastSamplingTime.Value;

            if app.IncluderespiratorymotionCheckBox.Value
                results.sim.respPeriod = app.RespPeriod.Value;
            else
                results.sim.respPeriod = 'N/A';
            end

            % the ground-truth simulation
            results.images.GroundTruth.img = app.IMG_CP;
            results.images.GroundTruth.timing = app.timing;

            % save the reconstruction information in a results.recon struct
            results.recon.trajectory = app.SamplingtrajectoryDropDown.Value;
            results.recon.DCEframelength    = app.DCE_frameLength.Value;
            results.recon.DCEframes         = app.nt;
            results.recon.DCE_R             = app.accelerationFactor.Text;

            results.recon.SNR                     = app.SNR;

            if app.MotioncorrectionCheckBox.Value
                results.recon.Resp = app.Resp;
            end

            % the final recon
            results.images.Recon.img = app.finalRecon;
            results.images.Recon.timing = app.timing_DCE;

            save([saveName '.mat'],'results','-v7.3')

            % inform of the saving
            msgbox(['results saved to ' saveName '.mat'], 'Saving complete')
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            delete(app.MRXCATwithERICUIFigure);  % close the app
            MRXCATwERIC;                      % re-open it
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MRXCATwithERICUIFigure = uifigure('Visible', 'off');
            app.MRXCATwithERICUIFigure.Name = 'MRXCAT with ERIC';
            app.MRXCATwithERICUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MRXCATwithERICUIFigureWindowButtonDown, true);
            app.TabGroup = uitabgroup(app.MRXCATwithERICUIFigure);
            app.Simulation = uitab(app.TabGroup);
            app.Simulation.Title = 'Simulation';
            app.Setup = uipanel(app.Simulation);
            app.Setup.Title = 'XCAT Simulation Parameters';
            app.leftrightEditFieldLabel = uilabel(app.Setup);
            app.leftrightEditFieldLabel.Text = 'left-right';
            app.leftrightRes = uieditfield(app.Setup, 'numeric');
            app.leftrightRes.Limits = [0.5 10];
            app.leftrightRes.ValueChangedFcn = createCallbackFcn(app, @leftrightResValueChanged, true);
            app.leftrightRes.Value = 1.7;
            app.anteriorposteriorEditFieldLabel = uilabel(app.Setup);
            app.anteriorposteriorEditFieldLabel.Text = 'anterior-posterior';
            app.anteriorposteriorRes = uieditfield(app.Setup, 'numeric');
            app.anteriorposteriorRes.Limits = [0.5 10];
            app.anteriorposteriorRes.ValueChangedFcn = createCallbackFcn(app, @anteriorposteriorResValueChanged, true);
            app.anteriorposteriorRes.Value = 1.7;
            app.footheadEditFieldLabel = uilabel(app.Setup);
            app.footheadEditFieldLabel.Text = 'foot-head';
            app.footheadRes = uieditfield(app.Setup, 'numeric');
            app.footheadRes.Limits = [0.5 10];
            app.footheadRes.ValueChangedFcn = createCallbackFcn(app, @footheadResValueChanged, true);
            app.footheadRes.Value = 3;
            app.SpatialResolutionmmLabel = uilabel(app.Setup);
            app.SpatialResolutionmmLabel.Text = 'Spatial Resolution (mm)';
            app.ReLoadDefaultParametersButton = uibutton(app.Setup, 'push');
            app.ReLoadDefaultParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ReLoadDefaultParametersButtonPushed, true);
            app.ReLoadDefaultParametersButton.Tooltip = {'Loads a pre-defined and pre-simulated ground truth.'};
            app.ReLoadDefaultParametersButton.Text = '(Re-) Load Default Parameters';
            app.TRmsLabel = uilabel(app.Setup);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TR_sim = uieditfield(app.Setup, 'numeric');
            app.TR_sim.Limits = [0.6 5000];
            app.TR_sim.ValueChangedFcn = createCallbackFcn(app, @TR_simValueChanged, true);
            app.TR_sim.Value = 5.1;
            app.TEmsLabel = uilabel(app.Setup);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TE_sim = uieditfield(app.Setup, 'numeric');
            app.TE_sim.Limits = [0.5 100];
            app.TE_sim.ValueChangedFcn = createCallbackFcn(app, @TE_simValueChanged, true);
            app.TE_sim.Value = 1.7;
            app.MRIAcquisitionLabel = uilabel(app.Setup);
            app.MRIAcquisitionLabel.Text = 'MRI Acquisition';
            app.Label = uilabel(app.Setup);
            app.Label.Text = 'α (°)';
            app.alpha_sim = uieditfield(app.Setup, 'numeric');
            app.alpha_sim.Limits = [1 90];
            app.alpha_sim.Value = 25;
            app.scantimeminLabel = uilabel(app.Setup);
            app.scantimeminLabel.Text = 'scan time (min)';
            app.scanTime = uieditfield(app.Setup, 'numeric');
            app.scanTime.Limits = [-0.1 10];
            app.scanTime.Value = 3;
            app.breathingperiodsLabel = uilabel(app.Setup);
            app.breathingperiodsLabel.Text = 'breathing period (s)';
            app.RespPeriod = uieditfield(app.Setup, 'numeric');
            app.RespPeriod.Limits = [0.5 15];
            app.RespPeriod.Value = 5;
            app.RespirationLabel = uilabel(app.Setup);
            app.RespirationLabel.Text = 'Respiration';
            app.IncluderespiratorymotionCheckBox = uicheckbox(app.Setup);
            app.IncluderespiratorymotionCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncluderespiratorymotionCheckBoxValueChanged, true);
            app.IncluderespiratorymotionCheckBox.Text = 'Include respiratory motion';
            app.IncluderespiratorymotionCheckBox.Value = true;
            app.CalculateNewGroundTruthButton = uibutton(app.Setup, 'push');
            app.CalculateNewGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNewGroundTruthButtonPushed, true);
            app.CalculateNewGroundTruthButton.Tooltip = {'Calculate new contrast (+motion) ground truth after adjusting parameters. This takes time!'};
            app.CalculateNewGroundTruthButton.Text = 'Calculate New Ground Truth';
            app.B0_sim_dropdown = uidropdown(app.Setup);
            app.B0_sim_dropdown.Items = {'3', '1.5'};
            app.B0_sim_dropdown.Tooltip = {'Main field used to grab specific T1, T2 values. Only 1.5 and 3.0 accepted'};
            app.B0_sim_dropdown.Value = '3';
            app.BTLabel = uilabel(app.Setup);
            app.BTLabel.Text = 'B₀ (T)';
            app.relaxivitysmMLabel = uilabel(app.Setup);
            app.relaxivitysmMLabel.Text = 'relaxivity (s⁻¹·mM⁻¹)';
            app.relaxivity = uieditfield(app.Setup, 'numeric');
            app.relaxivity.Value = 3.5;
            app.injectiontimesLabel = uilabel(app.Setup);
            app.injectiontimesLabel.Text = 'injection time (s)';
            app.injectionTime = uieditfield(app.Setup, 'numeric');
            app.injectionTime.Limits = [0 400];
            app.injectionTime.Value = 12;
            app.ContrastLabel = uilabel(app.Setup);
            app.ContrastLabel.Text = 'Contrast';
            app.contrastsamplingtimesLabel = uilabel(app.Setup);
            app.contrastsamplingtimesLabel.Text = 'contrast sampling time (s)';
            app.contrastSamplingTime = uieditfield(app.Setup, 'numeric');
            app.contrastSamplingTime.Limits = [0.01 5];
            app.contrastSamplingTime.Tooltip = {'With scan time, this determines the number of unique contrast uptake frames for simulation. High sampling time or lower scan time results in faster simulation!'};
            app.contrastSamplingTime.Value = 1;
            app.ViewGT = uipanel(app.Simulation);
            app.ViewGT.Title = 'Ground truth preview';
            app.Axial = uiaxes(app.ViewGT);
            title(app.Axial, 'Axial')
            app.Axial.XTick = [];
            app.Axial.YTick = [];
            app.Sagittal = uiaxes(app.ViewGT);
            title(app.Sagittal, 'Sagittal')
            app.Sagittal.XTick = [];
            app.Sagittal.YTick = [];
            app.Coronal = uiaxes(app.ViewGT);
            title(app.Coronal, 'Coronal')
            app.Coronal.XTick = [];
            app.Coronal.YTick = [];
            app.timeAxes = uiaxes(app.ViewGT);
            title(app.timeAxes, 'Time steps')
            xlabel(app.timeAxes, 'time (s)')
            app.StartStopAnimationButton = uibutton(app.ViewGT, 'state');
            app.StartStopAnimationButton.ValueChangedFcn = createCallbackFcn(app, @StartStopAnimationButtonValueChanged, true);
            app.StartStopAnimationButton.Text = 'Start/Stop Animation';
            app.AnimationSpeedfpsSliderLabel = uilabel(app.ViewGT);
            app.AnimationSpeedfpsSliderLabel.Text = 'Animation Speed (fps)';
            app.AnimationSpeedfpsSlider = uislider(app.ViewGT);
            app.AnimationSpeedfpsSlider.Limits = [0.5 8];
            app.AnimationSpeedfpsSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.AnimationSpeedfpsSlider.MinorTicks = [0 2 6 8 10 12 14 16 18 34];
            app.AnimationSpeedfpsSlider.Value = 2;
            app.ImageContrastLabel = uilabel(app.ViewGT);
            app.ImageContrastLabel.Text = 'Image Contrast';
            app.ImageContrastSlider = uislider(app.ViewGT);
            app.ImageContrastSlider.Limits = [0 1];
            app.ImageContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ImageContrastSliderValueChanged, true);
            app.ImageContrastSlider.MinorTicks = [];
            app.ImageContrastSlider.Value = 0.1;
            app.Acq_Recon = uipanel(app.Simulation);
            app.Acq_Recon.Title = 'Acquisition and Reconstruction';
            app.TrajectoryPlot = uiaxes(app.Acq_Recon);
            app.TrajectoryPlot.XTick = [];
            app.TrajectoryPlot.YTick = [];
            app.SamplingtrajectoryDropDownLabel = uilabel(app.Acq_Recon);
            app.SamplingtrajectoryDropDownLabel.Text = 'Sampling trajectory';
            app.SamplingtrajectoryDropDown = uidropdown(app.Acq_Recon);
            app.SamplingtrajectoryDropDown.Items = {'Cartesian', 'Pseudo-spiral', 'Stack-of-stars', 'Stack-of-spirals'};
            app.SamplingtrajectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingtrajectoryDropDownValueChanged, true);
            app.SamplingtrajectoryDropDown.Value = 'Pseudo-spiral';
            app.MotioncorrectionCheckBox = uicheckbox(app.Acq_Recon);
            app.MotioncorrectionCheckBox.Tooltip = {'extract respiratory signal, reconstruct along resp frames, and correct over pancreas. only available for non-cartesian trajectories'};
            app.MotioncorrectionCheckBox.Text = 'Motion correction';
            app.MotioncorrectionCheckBox.Value = true;
            app.ReconstructionParametersLabel = uilabel(app.Acq_Recon);
            app.ReconstructionParametersLabel.Text = 'Reconstruction Parameters';
            app.DCEtimeframelengthsLabel = uilabel(app.Acq_Recon);
            app.DCEtimeframelengthsLabel.Text = 'DCE time frame length (s)';
            app.DCE_frameLength = uieditfield(app.Acq_Recon, 'numeric');
            app.DCE_frameLength.Limits = [0.1 30];
            app.DCE_frameLength.Tooltip = {'User-defined. This value will be update to possible value after ''Calculate trajectory'' is pushed'};
            app.DCE_frameLength.Value = 3;
            app.CompressedsensingreconCheckBox = uicheckbox(app.Acq_Recon);
            app.CompressedsensingreconCheckBox.Tooltip = {'Perform BART temporal compressed sensing (over DCE frames or respiratory frames).'};
            app.CompressedsensingreconCheckBox.Text = 'Compressed sensing recon';
            app.CompressedsensingreconCheckBox.Value = true;
            app.matrixSize = uilabel(app.Acq_Recon);
            app.matrixSize.Text = 'matrix size: RL=220, AP=152, FH=35';
            app.CalculatetrajectoryButton = uibutton(app.Acq_Recon, 'push');
            app.CalculatetrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatetrajectoryButtonPushed, true);
            app.CalculatetrajectoryButton.Tooltip = {'Calculate chosen trajectory for matrix size and DCE frame length. If checkbox selected, the beginning portion will be plotted.'};
            app.CalculatetrajectoryButton.Text = {'Calculate '; 'trajectory'};
            app.PlottrajectoryCheckBox = uicheckbox(app.Acq_Recon);
            app.PlottrajectoryCheckBox.Tooltip = {'plot trajectory for one DCE frame'};
            app.PlottrajectoryCheckBox.Text = 'Plot trajectory';
            app.accelerationFactor = uilabel(app.Acq_Recon);
            app.accelerationFactor.Visible = 'off';
            app.accelerationFactor.Text = 'R per frame=10.5';
            app.numberOfDCEframes = uilabel(app.Acq_Recon);
            app.numberOfDCEframes.Visible = 'off';
            app.numberOfDCEframes.Text = 'number of frames=100';
            app.SamplereconstructButton = uibutton(app.Acq_Recon, 'push');
            app.SamplereconstructButton.ButtonPushedFcn = createCallbackFcn(app, @SamplereconstructButtonPushed, true);
            app.SamplereconstructButton.Tooltip = {'Perform full recon. Interim results are displayed and saved. Final interactive results are displayed upon completion'};
            app.SamplereconstructButton.Text = 'Sample / reconstruct';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ViewResults = uipanel(app.ResultsTab);
            app.ViewResults.Title = 'Ground Truth versus Reconstruction Results';
            app.Axial_results = uiaxes(app.ViewResults);
            title(app.Axial_results, 'Axial')
            app.Axial_results.XTick = [];
            app.Axial_results.YTick = [];
            app.Sagittal_results = uiaxes(app.ViewResults);
            title(app.Sagittal_results, 'Sagittal')
            app.Sagittal_results.XTick = [];
            app.Sagittal_results.YTick = [];
            app.Coronal_results = uiaxes(app.ViewResults);
            title(app.Coronal_results, 'Coronal')
            app.Coronal_results.XTick = [];
            app.Coronal_results.YTick = [];
            app.timeAxes_results = uiaxes(app.ViewResults);
            title(app.timeAxes_results, 'Time steps')
            xlabel(app.timeAxes_results, 'time (s)')
            app.Coronal_GT = uiaxes(app.ViewResults);
            title(app.Coronal_GT, 'Coronal')
            app.Coronal_GT.XTick = [];
            app.Coronal_GT.YTick = [];
            app.Sagittal_GT = uiaxes(app.ViewResults);
            title(app.Sagittal_GT, 'Sagittal')
            app.Sagittal_GT.XTick = [];
            app.Sagittal_GT.YTick = [];
            app.Axial_GT = uiaxes(app.ViewResults);
            title(app.Axial_GT, 'Axial')
            app.Axial_GT.XTick = [];
            app.Axial_GT.YTick = [];
            app.StartStopAnimationButton_results = uibutton(app.ViewResults, 'state');
            app.StartStopAnimationButton_results.ValueChangedFcn = createCallbackFcn(app, @StartStopAnimationButton_resultsValueChanged, true);
            app.StartStopAnimationButton_results.Text = 'Start/Stop Animation';
            app.AnimationSpeedfpsSlider_2Label = uilabel(app.ViewResults);
            app.AnimationSpeedfpsSlider_2Label.Text = 'Animation Speed (fps)';
            app.AnimationSpeedfpsSlider_results = uislider(app.ViewResults);
            app.AnimationSpeedfpsSlider_results.Limits = [0.5 8];
            app.AnimationSpeedfpsSlider_results.MajorTicks = [1 2 3 4 5 6 7 8];
            app.AnimationSpeedfpsSlider_results.MinorTicks = [0 2 6 8 10 12 14 16 18 34];
            app.AnimationSpeedfpsSlider_results.Value = 2;
            app.ImageContrastSlider_2Label = uilabel(app.ViewResults);
            app.ImageContrastSlider_2Label.Text = 'Image Contrast';
            app.ImageContrastSlider_results = uislider(app.ViewResults);
            app.ImageContrastSlider_results.Limits = [0 1];
            app.ImageContrastSlider_results.ValueChangedFcn = createCallbackFcn(app, @ImageContrastSlider_resultsValueChanged, true);
            app.ImageContrastSlider_results.MinorTicks = [];
            app.ImageContrastSlider_results.Value = 0.1;
            app.SavingTitle = uilabel(app.ResultsTab);
            app.SavingTitle.Text = 'Saving';
            app.SaveResultsCallback = uibutton(app.ResultsTab, 'push');
            app.SaveResultsCallback.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsCallbackButtonPushed, true);
            app.SaveResultsCallback.Text = 'Save results';
            app.RestartLabel = uilabel(app.ResultsTab);
            app.RestartLabel.Text = 'Restart';
            app.reset = uibutton(app.ResultsTab, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'reset app';
            % Show the figure after all components are created
            app.MRXCATwithERICUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRXCATwERIC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRXCATwithERICUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRXCATwithERICUIFigure)
        end
    end
end