classdef FOV_chooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FOV_chooserFigure          matlab.ui.Figure
        InstructionsTextArea       matlab.ui.control.TextArea
        InstructionsTextAreaLabel  matlab.ui.control.Label
        FinishedButton             matlab.ui.control.Button
        pointer3d_info             matlab.ui.control.Label
        Subplot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp      % MRXCAT app
        volume          % the 3D XCAT volume
        pointer         % the current point in 3D
        vol_sz          % the size of the image
    end
    
    properties (Access = public)
        startSlice = [] % the axial starting slice chosen by user
        endSlice = []   % the axial end slice chosen by user
    end
    
    methods (Access = private)
        function clipointer3d(app)
            pointer3d_in = app.pointer;
            pointer3d_out = pointer3d_in;
                if (pointer3d_in > app.vol_sz(1))
                    pointer3d_out = app.vol_sz(1);
                end
                if (pointer3d_in < 1)
                    pointer3d_out = 1;
                end
            app.pointer = pointer3d_out;
        end
        
        function plotslices(app)
            
            if ~isempty(app.startSlice) || ~isempty(app.endSlice)   % inform            
                text_str = sprintf('start slice: %i\nend slice: %i',...
                    app.startSlice,app.endSlice);
                app.pointer3d_info.Text = text_str;
                if ~isempty(app.startSlice) && ~isempty(app.endSlice)   % inform            
                text_str = sprintf('start slice: %i\nend slice: %i\nFH volume: %i mm',...
                    app.startSlice,app.endSlice,abs(app.endSlice-app.startSlice));
                app.pointer3d_info.Text = text_str;
                end
            else
                app.pointer3d_info.Text = '';
            end
          
            sliceYZ = squeeze(app.volume(app.pointer(1),:,:));
            max_xyz = max(sliceYZ(:));
            min_xyz = min(sliceYZ(:));
            clims = [ min_xyz max_xyz ];
            sliceZY = squeeze(permute(sliceYZ, [2 1 3]));
            
            imshow(sliceZY, clims, 'Parent',app.Subplot);
            axis(app.Subplot,'image');
            
            if ~isempty(app.startSlice)
                images.roi.Line('Parent',app.Subplot,...
                'Position',[1 app.startSlice; size(app.volume,2) app.startSlice], ...
                'LineWidth', 1, 'Color', 'r');
            end
            if ~isempty(app.endSlice)
                images.roi.Line('Parent',app.Subplot,...
                'Position',[1 app.endSlice; size(app.volume,2) app.endSlice], ...
                'LineWidth', 1, 'Color', 'g');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SliceBrowser_OpeningFcn(app, mainapp, IMG)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.volume = IMG;
            if (ndims(app.volume) ~= 3)
                error('Input volume must have 3 dimension');
            end
                        
            % set main wnd title
            set(gcf, 'Name', 'FOV Chooser')
            
            % init 3D pointer
            app.vol_sz = size(app.volume);
            
            app.pointer = floor(app.vol_sz/2)+1;
            plotslices(app);
            
        end

        % Window scroll wheel function: FOV_chooserFigure
        function FOV_chooserFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
            app.pointer(1) = app.pointer(1) + verticalScrollAmount*verticalScrollCount;
            clipointer3d(app);
            plotslices(app);
        end

        % Window button down function: FOV_chooserFigure
        function FOV_chooserFigureWindowButtonDown(app, event)
             imSize = size(app.volume,2:3);

             % check if point in the axis
             xx = round(app.Subplot.CurrentPoint);
             % check that point makes sense
             x = xx(1,2); y = xx(1,1);
             if x > 0 && x < imSize(1) && y > 0 && y < imSize(2)
                if ~isempty(app.startSlice)
                    app.endSlice = x;
                end
                if isempty(app.startSlice)
                    app.startSlice = x;
                end
             end
             plotslices(app);
        end

        % Key press function: FOV_chooserFigure
        function FOV_chooserFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'r'
                    app.startSlice = [];
                    app.endSlice = [];
                    app.pointer = app.vol_sz(1)/2;
            end
            plotslices(app);
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            % Delete the app
            app.FinishedButton.UserData = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FOV_chooserFigure = uifigure('Visible', 'off');
            app.FOV_chooserFigure.WindowButtonDownFcn = createCallbackFcn(app, @FOV_chooserFigureWindowButtonDown, true);
            app.FOV_chooserFigure.WindowScrollWheelFcn = createCallbackFcn(app, @FOV_chooserFigureWindowScrollWheel, true);
            app.FOV_chooserFigure.KeyPressFcn = createCallbackFcn(app, @FOV_chooserFigureKeyPress, true);
            app.FOV_chooserFigure.Tag = 'FOV_chooserFigure';
            app.Subplot = uiaxes(app.FOV_chooserFigure);
            title(app.Subplot, 'Coronal View')
            app.Subplot.XTick = [];
            app.Subplot.YTick = [];
            app.Subplot.Tag = 'Subplot';
            app.pointer3d_info = uilabel(app.FOV_chooserFigure);
            app.pointer3d_info.Tag = 'pointer3d_info';
            app.pointer3d_info.Text = 'Info';
            app.FinishedButton = uibutton(app.FOV_chooserFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Tooltip = {'sends data back to XCAT, and closes this app'};
            app.FinishedButton.Text = 'Finished';
            app.InstructionsTextAreaLabel = uilabel(app.FOV_chooserFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.FOV_chooserFigure);
            app.InstructionsTextArea.Editable = 'off';
            app.InstructionsTextArea.Tooltip = {''};
            app.InstructionsTextArea.Value = {'- Scroll to view coronal slices'; '- (1st) left click: set starting (axial) slice'; '- (2nd) left click: set ending (axial) slice'; '- shortcut ''r'': reset view/slices'; '- Click ''Finished'' when done'};
            % Show the figure after all components are created
            app.FOV_chooserFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FOV_chooser(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FOV_chooserFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)SliceBrowser_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FOV_chooserFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FOV_chooserFigure)
        end
    end
end